classdef CodeIntergrate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodeIntergrateToolUIFigure      matlab.ui.Figure
        CodeIntergrateButton            matlab.ui.control.Button
        ChooseFileButton                matlab.ui.control.Button
        CodeIntergrateFileEditFieldLabel  matlab.ui.control.Label
        CodeIntergrateFileEditField     matlab.ui.control.EditField
        SaveFileButton                  matlab.ui.control.Button
        readyLabel                      matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        ResetButton                     matlab.ui.control.Button
        ModelAddressEditFieldLabel      matlab.ui.control.Label
        ModelAddressEditField           matlab.ui.control.EditField
        SimulinkCodeFileTextAreaLabel   matlab.ui.control.Label
        SimulinkCodeFileTextArea        matlab.ui.control.TextArea
        CodeCopyAddressTextAreaLabel    matlab.ui.control.Label
        CodeCopyAddressTextArea         matlab.ui.control.TextArea
        ChooseModelButton               matlab.ui.control.Button
        ChooseFolderButton              matlab.ui.control.Button
        ChooseFolderButton_2            matlab.ui.control.Button
        DispCodeCopyFilesTextAreaLabel  matlab.ui.control.Label
        DispCodeCopyFilesTextArea       matlab.ui.control.TextArea
        CodeIntergrateToolLabel         matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        CodeCopyButton                  matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        CodeGenerationButton            matlab.ui.control.Button
        OneStepButton                   matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        ModelVersionLabel               matlab.ui.control.Label
        ModelVersion                    matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             load('.\matlab.mat');
%             app.CodeIntergrateFileEditField.Value = basecodefile;
%             app.ModelAddressEditField.Value = modename;
%             app.SimulinkCodeFileTextArea.Value = codefile;
%             app.CodeCopyAddressTextArea.Value = copyadress;
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile( ...
                     {'*.c';'*.h';'*.m';'*.mdl';'*.mat';'*.*'},'Pick a file');
            if pathname==0
                return;
            end
            app.CodeIntergrateFileEditField.Value = [pathname,filename];
        end

        % Button pushed function: CodeIntergrateButton
        function CodeIntergrateButtonPushed(app, event)
            app.readyLabel.Text = 'Fail ! ! !';
            app.Lamp.Color = 'r';
            %copyflg=codecopy(app.SimulinkCodeFileTextArea.Value,app.CodeCopyAddressTextArea.Value); %代码集成不进行代码拷贝
            codeflg=codetool(app.ModelAddressEditField.Value,app.CodeIntergrateFileEditField.Value);
            if codeflg == 1 %&& copyflg == 1
                app.readyLabel.Text = 'Sucess ! ! !';
            else
                app.readyLabel.Text = 'Fail ! ! !';
                return;
            end
            %app.StateEditField.Value = 'ok';
            app.Lamp.Color = 'g';
            
            %%refresh
            listfiles = app.CodeCopyAddressTextArea.Value{1};
            copycodeallfiles = dir(listfiles);
            len = length(copycodeallfiles);
            allfiles = '';
            for i=3:len
                allfiles = [allfiles,copycodeallfiles(i).name,'     ',copycodeallfiles(i).date,newline];
            end
            app.DispCodeCopyFilesTextArea.Value = allfiles;
            msgbox("代码集成完成","提示");
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            basecodefile = app.CodeIntergrateFileEditField.Value;
            modename = app.ModelAddressEditField.Value;
            codefile = app.SimulinkCodeFileTextArea.Value;
            copyadress = app.CodeCopyAddressTextArea.Value;
            uisave({'basecodefile','modename','codefile','copyadress'});
            %delete(app.CodeIntergrateToolUIFigure);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.readyLabel.Text = 'reset';
            app.Lamp.Color = 'r';
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            app.DispCodeCopyFilesTextArea.Value = '';
            if isempty(app.ModelAddressEditField.Value) == 0
                [~, allname, ~] = fileparts(app.ModelAddressEditField.Value);
                ModelInfo = Simulink.MDLInfo(allname);
                app.ModelVersion.Value = ModelInfo.ModelVersion;
            else
                app.ModelVersion.Value = '0.0';
            end
        end

        % Button pushed function: ChooseModelButton
        function ChooseModelButtonPushed(app, event)
            [modelname, pathmodel] = uigetfile('*.slx','Pick a model');
            if pathmodel==0
                return;
            end
            app.ModelAddressEditField.Value = [pathmodel,modelname];
            [~, allname, ~] = fileparts(app.ModelAddressEditField.Value);
            ModelInfo = Simulink.MDLInfo(allname);
            app.ModelVersion.Value = ModelInfo.ModelVersion;
            %app.ModelVersion.Value = get_param(allname,'ModelVersion');
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            [codename, pathcode] = uigetfile({'*.c;*.h'},'Pick a code file','MultiSelect', 'on');
            if pathcode==0
                return;
            end
            len = length(codename);
            code = '';
            for i=1:len
                code = [code,pathcode,codename{i},newline];
            end
            
            %app.SimulinkCodeFileTextArea.Value = pathcode;
            app.SimulinkCodeFileTextArea.Value = code;
        end

        % Button pushed function: ChooseFolderButton_2
        function ChooseFolderButton_2Pushed(app, event)
            codecopyadress = uigetdir({},'pick a folder');
            if codecopyadress==0
                return;
            end
            app.CodeCopyAddressTextArea.Value = codecopyadress;
            copycodeallfiles = dir(codecopyadress);
            len = length(copycodeallfiles);
            allfiles = '';
            for i=3:len
                allfiles = [allfiles,copycodeallfiles(i).name,'     ',copycodeallfiles(i).date,newline];
            end
            app.DispCodeCopyFilesTextArea.Value = allfiles;
            % app.ModelVersionLabel.Text = 1;//copycodeallfiles(2);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            load('.\matlab.mat');
            app.CodeIntergrateFileEditField.Value = basecodefile;
            app.ModelAddressEditField.Value = modename;
            app.SimulinkCodeFileTextArea.Value = codefile;
            app.CodeCopyAddressTextArea.Value = copyadress;
        end

        % Button pushed function: CodeCopyButton
        function CodeCopyButtonPushed(app, event)
            app.readyLabel.Text = 'Fail ! ! !';
            app.Lamp_2.Color = 'r';
            copyflg=codecopy(app.SimulinkCodeFileTextArea.Value,app.CodeCopyAddressTextArea.Value);
            if  copyflg == 1
                app.readyLabel.Text = 'Sucess ! ! !';
            else
                app.readyLabel.Text = 'Fail ! ! !';
                return;
            end
            %app.StateEditField.Value = 'ok';
            app.Lamp_2.Color = 'g';
            
            %%refresh
            listfiles = app.CodeCopyAddressTextArea.Value{1};
            copycodeallfiles = dir(listfiles);
            len = length(copycodeallfiles);
            allfiles = '';
            for i=3:len
                allfiles = [allfiles,copycodeallfiles(i).name,'     ',copycodeallfiles(i).date,newline];
            end
            app.DispCodeCopyFilesTextArea.Value = allfiles;
            msgbox("代码拷贝完成","提示");
        end

        % Button pushed function: CodeGenerationButton
        function CodeGenerationButtonPushed(app, event)
            app.readyLabel.Text = 'Fail ! ! !';
            app.Lamp_4.Color = 'r';
            %app.Lamp_5.Color = 'r';

            codegen=codeGeneration(app.ModelAddressEditField.Value);
%             if loadflg == 1
%                 app.Lamp_5.Color = 'g';
%             end
            
            if codegen == 1
                app.readyLabel.Text = 'Sucess ! ! !';
            else
                app.readyLabel.Text = 'Fail ! ! !';
                return;
            end
            %app.StateEditField.Value = 'ok';
            app.Lamp_4.Color = 'g';
            msgbox("代码生成完成","提示");
        end

        % Button pushed function: OneStepButton
        function OneStepButtonPushed(app, event)
            app.readyLabel.Text = 'Fail ! ! !';
            app.Lamp.Color = 'r';
            app.Lamp_2.Color = 'r';
            app.Lamp_3.Color = 'r';
            app.Lamp_4.Color = 'r';
            %app.Lamp_5.Color = 'r';
%             path = app.ModelAddressEditField.Value;
%             run loaddata.m;
%             loadflg = y;
%             if loadflg == 1
%                 app.Lamp_5.Color = 'g';
%             end
            
            codegen=codeGeneration(app.ModelAddressEditField.Value);
            if codegen == 1
                app.Lamp_4.Color = 'g';
            end
            
            copyflg=codecopy(app.SimulinkCodeFileTextArea.Value,app.CodeCopyAddressTextArea.Value);
            if copyflg == 1
                app.Lamp_2.Color = 'g';
            end
            
            codeflg=codetool(app.ModelAddressEditField.Value,app.CodeIntergrateFileEditField.Value);
            if codeflg == 1
                app.Lamp.Color = 'g';
            end
            
            if codegen == 1 && copyflg == 1 && codeflg == 1
                app.readyLabel.Text = 'Sucess ! ! !';
            else
                app.readyLabel.Text = 'Fail ! ! !';
                return;
            end
            %app.StateEditField.Value = 'ok';
            app.Lamp_3.Color = 'g';
            
            %%refresh
            listfiles = app.CodeCopyAddressTextArea.Value{1};
            copycodeallfiles = dir(listfiles);
            len = length(copycodeallfiles);
            allfiles = '';
            for i=3:len
                allfiles = [allfiles,copycodeallfiles(i).name,'     ',copycodeallfiles(i).date,newline];
            end
            app.DispCodeCopyFilesTextArea.Value = allfiles;
            msgbox("一键操作完成","提示");
        end

        % Callback function
        function LoadDataButtonPushed(app, event)
%             app.readyLabel.Text = 'Fail ! ! !';
%             app.Lamp_5.Color = 'r';
%             path = app.ModelAddressEditField.Value;
%             run loaddata.m;
%             loadflg = y;
%             if loadflg == 1
%                 app.readyLabel.Text = 'Sucess ! ! !';
%             else
%                 app.readyLabel.Text = 'Fail ! ! !';
%                 return;
%             end
%             %app.StateEditField.Value = 'ok';
%             app.Lamp_5.Color = 'g';
        end

        % Value changed function: ModelVersion
        function ModelVersionValueChanged(app, event)
            value = app.ModelVersion.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodeIntergrateToolUIFigure = uifigure;
            app.CodeIntergrateToolUIFigure.Name = 'Code Intergrate Tool';
            app.CodeIntergrateButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.CodeIntergrateButton.ButtonPushedFcn = createCallbackFcn(app, @CodeIntergrateButtonPushed, true);
            app.CodeIntergrateButton.Text = 'Code Intergrate';
            app.ChooseFileButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.CodeIntergrateFileEditFieldLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.CodeIntergrateFileEditFieldLabel.Text = 'Code Intergrate File：';
            app.CodeIntergrateFileEditField = uieditfield(app.CodeIntergrateToolUIFigure, 'text');
            app.SaveFileButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.readyLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.readyLabel.Text = 'ready';
            app.Lamp = uilamp(app.CodeIntergrateToolUIFigure);
            app.ResetButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ModelAddressEditFieldLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.ModelAddressEditFieldLabel.Text = 'Model Address：';
            app.ModelAddressEditField = uieditfield(app.CodeIntergrateToolUIFigure, 'text');
            app.SimulinkCodeFileTextAreaLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.SimulinkCodeFileTextAreaLabel.Text = 'Simulink Code File：';
            app.SimulinkCodeFileTextArea = uitextarea(app.CodeIntergrateToolUIFigure);
            app.CodeCopyAddressTextAreaLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.CodeCopyAddressTextAreaLabel.Text = 'Code Copy Address：';
            app.CodeCopyAddressTextArea = uitextarea(app.CodeIntergrateToolUIFigure);
            app.ChooseModelButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.ChooseModelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseModelButtonPushed, true);
            app.ChooseModelButton.Text = 'Choose Model';
            app.ChooseFolderButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.ChooseFolderButton_2 = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.ChooseFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButton_2Pushed, true);
            app.ChooseFolderButton_2.Text = 'Choose Folder';
            app.DispCodeCopyFilesTextAreaLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.DispCodeCopyFilesTextAreaLabel.Text = 'Disp Code Copy Files：';
            app.DispCodeCopyFilesTextArea = uitextarea(app.CodeIntergrateToolUIFigure);
            app.DispCodeCopyFilesTextArea.Editable = 'off';
            app.CodeIntergrateToolLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.CodeIntergrateToolLabel.Text = 'Code Intergrate Tool';
            app.LoadFileButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.Label = uilabel(app.CodeIntergrateToolUIFigure);
            app.Label.Text = '';
            app.CodeCopyButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.CodeCopyButton.ButtonPushedFcn = createCallbackFcn(app, @CodeCopyButtonPushed, true);
            app.CodeCopyButton.Text = 'Code Copy';
            app.Lamp_2 = uilamp(app.CodeIntergrateToolUIFigure);
            app.CodeGenerationButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.CodeGenerationButton.ButtonPushedFcn = createCallbackFcn(app, @CodeGenerationButtonPushed, true);
            app.CodeGenerationButton.Text = 'Code Generation';
            app.OneStepButton = uibutton(app.CodeIntergrateToolUIFigure, 'push');
            app.OneStepButton.ButtonPushedFcn = createCallbackFcn(app, @OneStepButtonPushed, true);
            app.OneStepButton.Text = 'One Step';
            app.Lamp_3 = uilamp(app.CodeIntergrateToolUIFigure);
            app.Lamp_4 = uilamp(app.CodeIntergrateToolUIFigure);
            app.ModelVersionLabel = uilabel(app.CodeIntergrateToolUIFigure);
            app.ModelVersionLabel.Text = 'Model Version';
            app.ModelVersion = uieditfield(app.CodeIntergrateToolUIFigure, 'text');
            app.ModelVersion.ValueChangedFcn = createCallbackFcn(app, @ModelVersionValueChanged, true);
            app.ModelVersion.Editable = 'off';
            app.ModelVersion.Value = '0.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CodeIntergrate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodeIntergrateToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodeIntergrateToolUIFigure)
        end
    end
end